classdef FrontPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PAMButton                       matlab.ui.control.Button
        ContinuouswaveModulationButton  matlab.ui.control.Button
        SignalEditField                 matlab.ui.control.EditField
        SignalEditFieldLabel            matlab.ui.control.Label
        inHzLabel                       matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditField_2Label       matlab.ui.control.Label
        inLabel                         matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        inVLabel                        matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        SignalAnalysisWorkspaceLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        amp
        freq
        phy
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinuouswaveModulationButton
        function ContinuouswaveModulationButtonPushed(app, event)
            app.amp = app.AmplitudeEditField.Value;
            app.freq = app.FrequencyEditField.Value;
            app.phy = app.PhaseEditField.Value;
            modesPage(app);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
           if ( isempty(app.AmplitudeEditField.Value) || isempty(app.FrequencyEditField.Value) || isempty(app.PhaseEditField.Value) )==0
            A = app.AmplitudeEditField.Value;
            f = app.FrequencyEditField.Value;
            p = app.PhaseEditField.Value;
            app.SignalEditField.Value = strcat(num2str(A),'cos( 2',char(960),'(',num2str(f),')t + ',num2str(p),char(176),' )') ;
           end
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
           if ( isempty(app.AmplitudeEditField.Value) || isempty(app.FrequencyEditField.Value) || isempty(app.PhaseEditField.Value) )==0
            A = app.AmplitudeEditField.Value;
            f = app.FrequencyEditField.Value;
            p = app.PhaseEditField.Value;
            app.SignalEditField.Value = strcat(num2str(A),'cos( 2',char(960),'(',num2str(f),')t + ',num2str(p),char(176),' )') ;
           end
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
           if ( isempty(app.AmplitudeEditField.Value) || isempty(app.FrequencyEditField.Value) || isempty(app.PhaseEditField.Value) )==0
            A = app.AmplitudeEditField.Value;
            f = app.FrequencyEditField.Value;
            p = app.PhaseEditField.Value;
            app.SignalEditField.Value = strcat(num2str(A),'cos( 2',char(960),'(',num2str(f),')t + ',num2str(p),char(176),' )') ;
           end
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/home?authuser=0');
        end

        % Button pushed function: PAMButton
        function PAMButtonPushed(app, event)
            app.amp = app.AmplitudeEditField.Value;
            app.freq = app.FrequencyEditField.Value;
            app.phy = app.PhaseEditField.Value;
            modesPage_2(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.SignalAnalysisWorkspaceLabel = uilabel(app.UIFigure);
            app.SignalAnalysisWorkspaceLabel.Text = 'Signal Analysis Workspace';
            app.AmplitudeEditField_2Label = uilabel(app.UIFigure);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.inVLabel = uilabel(app.UIFigure);
            app.inVLabel.Text = '( in V )';
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.inLabel = uilabel(app.UIFigure);
            app.inLabel.Text = '( in ° )';
            app.FrequencyEditField_2Label = uilabel(app.UIFigure);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.inHzLabel = uilabel(app.UIFigure);
            app.inHzLabel.Text = '( in Hz )';
            app.SignalEditFieldLabel = uilabel(app.UIFigure);
            app.SignalEditFieldLabel.Text = 'Signal';
            app.SignalEditField = uieditfield(app.UIFigure, 'text');
            app.ContinuouswaveModulationButton = uibutton(app.UIFigure, 'push');
            app.ContinuouswaveModulationButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuouswaveModulationButtonPushed, true);
            app.ContinuouswaveModulationButton.Text = 'Continuous-wave Modulation';
            app.PAMButton = uibutton(app.UIFigure, 'push');
            app.PAMButton.ButtonPushedFcn = createCallbackFcn(app, @PAMButtonPushed, true);
            app.PAMButton.Text = 'PAM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrontPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end