classdef PPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        TabGroup                 matlab.ui.container.TabGroup
        TimeDomainTab            matlab.ui.container.Tab
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        AmplitudeKnob            matlab.ui.control.Knob
        modulated                matlab.ui.control.UIAxes
        carrier                  matlab.ui.control.UIAxes
        message                  matlab.ui.control.UIAxes
        FrequencyDomainTab       matlab.ui.container.Tab
        modulated_2              matlab.ui.control.UIAxes
        carrier_2                matlab.ui.control.UIAxes
        message_2                matlab.ui.control.UIAxes
        DemodulatedSignalTab     matlab.ui.container.Tab
        Freq_domain              matlab.ui.control.UIAxes
        Time_domain              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        modesPageObj_2
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)
            t=0:0.0001:1;
            s=sawtooth(2*pi*10*t+pi);
            m=0.75*sin(2*pi*1*t);
            n=length(s);
            for i=1:n
                if (m(i)>=s(i))
                    pam(i)=1;
                elseif (m(i)<=s(i))
                    pam(i)=-1;
                end
            end
            
            % PPM Modulation
            fc = 100; % Carrier frequency
            fs = 10000; % Sampling frequency
            t_mod = 0:1/fs:1; % Time vector for modulation
            carrier = cos(2*pi*fc*t_mod); % Carrier signal
            carrier = carrier(1:end-1); % Removing last element from carrier signal
            modulated = zeros(1,length(pam)*length(t_mod));
            for i=1:length(pam)
                if pam(i)==1
                    modulated((i-1)*length(t_mod)+1:i*length(t_mod))=carrier;
                elseif pam(i)==-1
                    modulated((i-1)*length(t_mod)+1:i*length(t_mod))=-carrier;
                end
            end
            
            % PPM Demodulation
            demodulated=zeros(1,length(pam));
            for i=1:length(pam)
                if pam(i)==1
                    demodulated(i)=max(modulated((i-1)*length(t_mod)+1:i*length(t_mod)));
                elseif pam(i)==-1
                    demodulated(i)=min(modulated((i-1)*length(t_mod)+1:i*length(t_mod)));
                end
            end
            
            % Plotting in time domain
            figure;
            subplot(2,2,1);
            plot(t,pam,'g');
            ylabel('Amplitude');
            axis([0 1 -1.5 1.5]);
            xlabel('Time index');
            title('PAM Wave');
            grid on;
            
            subplot(2,2,2);
            plot(t,m,'r');
            ylabel('Amplitude');
            axis([0 1 -1.5 1.5]);
            xlabel('Time index');
            title('Message Signal');
            grid on;
            
            subplot(2,2,3);
            plot(t_mod,carrier,'b');
            ylabel('Amplitude');
            axis([0 1 -1.5 1.5]);
            xlabel('Time index');
            title('Carrier Signal');
            grid on;
            
            subplot(2,2,4);
            plot(modulated,'m');
            ylabel('Amplitude');
            axis([0 length(modulated) -2 2]);
            xlabel('Time index');
            title('Modulated Signal');
            grid on;
            
            figure;
            subplot(3,1,1);
            plot(demodulated,'k');
            ylabel('Amplitude');
            axis([0 length(demodulated) -2 2]);
            xlabel('Time index');
            title('Demodulated Signal');
            grid on;
            
            % Plotting in frequency domain
            N = length(modulated);
            f = (-N/2:N/2-1)*(fs/N); % Frequency vector
            modulated_fft = fftshift(abs(fft(modulated,N)));
            demodulated_fft = fftshift(abs(fft(demodulated,N)));
            
            subplot(3,1,2);
            plot(f/1000,modulated_fft,'m'); % Frequency in kHz
            ylabel('Magnitude (dB)');
            xlabel('Frequency (kHz)');
            title('Modulated Signal Spectrum');
            
            subplot(3,1,3);
            plot(f/1000,demodulated_fft,'k'); % Frequency in kHz
            ylabel('Magnitude (dB)');
            xlabel('Frequency (kHz)');
            title('Demodulated Signal Spectrum');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.modesPageObj_2 = modesPage;
            app.Freq = app.modesPageObj_2.frontPageObj.freq;
            app.Amp = app.modesPageObj_2.frontPageObj.amp;
            app.Phse = app.modesPageObj_2.frontPageObj.phy;
            mainFun(app, app.modesPageObj_2.frontPageObj.amp, app.modesPageObj_2.frontPageObj.freq, app.modesPageObj_2.frontPageObj.phy);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/amplitude-modulation?authuser=0');
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Time')
            ylabel(app.message_2, 'Volts(V)')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Time')
            ylabel(app.carrier_2, 'Volts(V)')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Time')
            ylabel(app.modulated_2, 'Volts(V)')
            zlabel(app.modulated_2, 'Z')
            app.DemodulatedSignalTab = uitab(app.TabGroup);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.Time_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Time_domain, 'Time')
            xlabel(app.Time_domain, 'X')
            ylabel(app.Time_domain, 'Y')
            zlabel(app.Time_domain, 'Z')
            app.Freq_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Freq_domain, 'Freq')
            xlabel(app.Freq_domain, 'X')
            ylabel(app.Freq_domain, 'Y')
            zlabel(app.Freq_domain, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end