classdef PWM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        TabGroup                 matlab.ui.container.TabGroup
        TimeDomainTab            matlab.ui.container.Tab
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        AmplitudeKnob            matlab.ui.control.Knob
        pwm_plot                 matlab.ui.control.UIAxes
        modulated                matlab.ui.control.UIAxes
        carrier                  matlab.ui.control.UIAxes
        message                  matlab.ui.control.UIAxes
        FrequencyDomainTab       matlab.ui.container.Tab
        modulated_2              matlab.ui.control.UIAxes
        carrier_2                matlab.ui.control.UIAxes
        message_2                matlab.ui.control.UIAxes
        DemodulatedSignalTab     matlab.ui.container.Tab
        Freq_domain              matlab.ui.control.UIAxes
        Time_domain              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modesPageObj_2
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)
            t=0:0.0001:1;
            s=sawtooth(2*pi*10*t+pi);
            m=A*sin(2*pi*F*t);
            n=length(s);
            for i=1:n
            if (m(i)>=s(i))
                pwm(i)=1;
            elseif (m(i)<=s(i))
                pwm(i)=0;
            end
            end
            
            % Modulation
            fc = 100*F; % Carrier frequency
            fs = 10000*F; % Sampling frequency
            t_mod = 0:1/fs:1; % Time vector for modulation
            carr = cos(2*pi*fc*t_mod); % Carrier signal
            % carrier = carrier(1:end-1); % Removing last element from carrier signal
            modula = pwm.*carr; % Modulated signal
            
            % Demodulation
            [b,a] = butter(5,fc/(fs/2)); % Butterworth filter of order 5
            demodulated = filtfilt(b,a,modula); % Demodulated signal
            
            % Plotting in time domain
            app.pwm_plot.XLim([0 1])
            app.message.XLim([0 1])
            app.carrier.XLim([0 1])
            app.modulated.XLim([0 1])
            app.Time_domain.XLim([0 1])

            app.pwm_plot.YLim([-1.5 1.5])
            app.message.YLim([-1.5 1.5])
            app.carrier.YLim([-1.5 1.5])
            app.modulated.YLim([-1.5 1.5])
            app.Time_domain.YLim([-1.5 1.5])

            plot(app.pwm_plot, t, pwm,'g');
%             title('PWM Wave');

            
            plot(app.message, t,m,'r');

%             title('Message Signal');

            
            plot(app.carrier,t_mod,carr,'b');

%             title('Carrier Signal');

            
            plot(app.modulated,t_mod,modula,'m');

%             title('Modulated Signal');

            
            plot(app.Time_domain,t_mod,demodulated,'k');

%             title('Demodulated Signal');

            
            % Plotting in frequency domain
            N = length(modula);
            f = (-N/2:N/2-1)*(fs/N); % Frequency vector
            modulated_fft = fftshift(abs(fft(modula,N)));
            demodulated_fft = fftshift(abs(fft(demodulated,N)));
            message_fft = fftshift(abs(fft(modula,N)));
            carrier_fft = fftshift(abs(fft(carr,N)));


            plot(app.message_2,f/1000,message_fft);
%             title('Single-Sided Amplitude Spectrum of Message Signal')             
            plot(app.carrier_2, f/1000,carrier_fft); 
%              title('Single-Sided Amplitude Spectrum of Carrier Signal') 
            
            plot(app.modulated_2,f/1000,modulated_fft,'m'); % Frequency in kHz
%             title('Modulated Signal Spectrum');
            
            plot(app.Freq_domain,f/1000,demodulated_fft,'k'); % Frequency in kHz
%             title('Demodulated Signal Spectrum');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modesPage)
            app.modesPageObj_2 = modesPage;
            app.Freq = app.modesPageObj_2.frontPageObj.freq;
            app.Amp = app.modesPageObj_2.frontPageObj.amp;
            app.Phse = app.modesPageObj_2.frontPageObj.phy;
            mainFun(app, app.modesPageObj_2.frontPageObj.amp, app.modesPageObj_2.frontPageObj.freq, app.modesPageObj_2.frontPageObj.phy);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/amplitude-modulation?authuser=0');
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);  
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.pwm_plot = uiaxes(app.TimeDomainTab);
            title(app.pwm_plot, 'PWM Signal')
            xlabel(app.pwm_plot, 'Time')
            ylabel(app.pwm_plot, 'Volts(V)')
            zlabel(app.pwm_plot, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Time')
            ylabel(app.message_2, 'Volts(V)')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Time')
            ylabel(app.carrier_2, 'Volts(V)')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Time')
            ylabel(app.modulated_2, 'Volts(V)')
            zlabel(app.modulated_2, 'Z')
            app.DemodulatedSignalTab = uitab(app.TabGroup);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.Time_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Time_domain, 'Time')
            xlabel(app.Time_domain, 'X')
            ylabel(app.Time_domain, 'Y')
            zlabel(app.Time_domain, 'Z')
            app.Freq_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Freq_domain, '0')
            xlabel(app.Freq_domain, 'X')
            ylabel(app.Freq_domain, 'Y')
            zlabel(app.Freq_domain, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PWM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end