classdef graphPage_PM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        TabGroup                 matlab.ui.container.TabGroup
        TimeDomainTab            matlab.ui.container.Tab
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        AmplitudeKnob            matlab.ui.control.Knob
        modulated                matlab.ui.control.UIAxes
        carrier                  matlab.ui.control.UIAxes
        message                  matlab.ui.control.UIAxes
        FrequencyDomainTab       matlab.ui.container.Tab
        modulated_2              matlab.ui.control.UIAxes
        carrier_2                matlab.ui.control.UIAxes
        message_2                matlab.ui.control.UIAxes
        DemodulatedSignalTab     matlab.ui.container.Tab
        Freq_domain              matlab.ui.control.UIAxes
        Time_domain              matlab.ui.control.UIAxes
        AnalysisTab              matlab.ui.container.Tab
        Error_signal             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        modesPageObj 
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)
            % Define message signal
            fm = F;
            Am = A;
            Ac = A;
            t = 0:0.001:1;
            msg = Am*sind(2*pi*fm*t);
            
            % Define carrier signal
            fc = 10*fm;
            carr = Ac*cosd(2*pi*fc*t);
            
            % Modulate message signal with carrier signal
            beta = 10;
            s = zeros(size(t));
            for i=1:length(t)
                s(i) = cos(2*pi*fc*t(i)+beta*msg(i));
            end
            
            % Demodulate modulated signal
            sdemod = zeros(size(t));
            for i=1:length(t)
                sdemod(i) = s(i)*cos(2*pi*fc*t(i));
            end

            % Plot signals in time domain
            plot(app.message,t,msg);
%             title('Message Signal');
            
            plot(app.carrier,t,carr);
%             title('Carrier Signal');
            
            plot(app.modulated,t,s);
%             title('Modulated Signal');
            
            plot(app.Time_domain,t,sdemod);
%             title('Demodulated Signal');
            
            % Plot signals in frequency domain
            plot(app.message_2,abs(fft(msg)));
%             title('Message Signal');
            
            plot(app.carrier_2,abs(fft(carr)));
%             title('Carrier Signal');
            
            plot(app.modulated_2,abs(fft(s)));
%             title('Modulated Signal');
            
            plot(app.Freq_domain,abs(fft(sdemod)));
%             title('Demodulated Signal');    
error=msg-sdemod;
plot(app.Error_signal,t,error);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modesPage)
            app.modesPageObj = modesPage;
            app.Freq = app.modesPageObj.frontPageObj.freq;
            app.Amp = app.modesPageObj.frontPageObj.amp;
            app.Phse = app.modesPageObj.frontPageObj.phy;
            mainFun(app, app.modesPageObj.frontPageObj.amp, app.modesPageObj.frontPageObj.freq, app.modesPageObj.frontPageObj.phy);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/amplitude-modulation?authuser=0');
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
             if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Time')
            ylabel(app.message_2, 'Volts(V)')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Time')
            ylabel(app.carrier_2, 'Volts(V)')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Time')
            ylabel(app.modulated_2, 'Volts(V)')
            zlabel(app.modulated_2, 'Z')
            app.DemodulatedSignalTab = uitab(app.TabGroup);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.Time_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Time_domain, 'Time')
            xlabel(app.Time_domain, 'X')
            ylabel(app.Time_domain, 'Y')
            zlabel(app.Time_domain, 'Z')
            app.Freq_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Freq_domain, 'Freq')
            xlabel(app.Freq_domain, 'X')
            ylabel(app.Freq_domain, 'Y')
            zlabel(app.Freq_domain, 'Z')
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Error_signal = uiaxes(app.AnalysisTab);
            title(app.Error_signal, 'Error Signal')
            xlabel(app.Error_signal, 'Time')
            ylabel(app.Error_signal, 'Amplitude')
            zlabel(app.Error_signal, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphPage_PM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end