classdef graphPage_dsbsc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        TabGroup                 matlab.ui.container.TabGroup
        TimeDomainTab            matlab.ui.container.Tab
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        AmplitudeKnob            matlab.ui.control.Knob
        modulated                matlab.ui.control.UIAxes
        carrier                  matlab.ui.control.UIAxes
        message                  matlab.ui.control.UIAxes
        FrequencyDomainTab       matlab.ui.container.Tab
        modulated_2              matlab.ui.control.UIAxes
        carrier_2                matlab.ui.control.UIAxes
        message_2                matlab.ui.control.UIAxes
        DemodulatedSignalTab     matlab.ui.container.Tab
        timeDomain               matlab.ui.control.UIAxes
        freqDomain               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modesPageObj 
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)
            %% DSB SC MODULATION AND DEMODULATION.
            
            %===========DEFINING THE SIGNALS==================
            t = 0:0.001:1; 
            m = 1;
            Am = A;
            Ac = Am / m;
            fm = F;
            fc = 10*fm;
            msg = Am*sin(2*pi*fm*t);    %message signal
            carr = Ac*sin(2*pi*fc*t);   %carrier signal
            fs = 1000*fm;                  %sampling frequency
            
            %% DSB SC MODULATION AND DEMODULATION
            %===========DSB SC IN TIME DOMAIN==================
            dsb_sc = msg.*carr; %dsb sc modulated wave
            
            %=====DSB SC IN FREQUENCY DOMAIN============
            ld=length (dsb_sc);
            f=linspace (-fs/2,fs/2,ld);
            DSB_SC=fftshift (fft (dsb_sc,ld)/ld); %frequency spectrum of dsb_sc modulated signal.
            
            %===========DSB SC DEMODULATION TIME DOMAIN============
%             msg_r=abs(hilbert(dsb_sc)).*2.*cos(2*pi*100*t);%demodulated signal

%             -------- Edited ME --------
            msg_r = 2*(dsb_sc.*carr)/(Ac*Am); %demodulated signal
            msg_r = lowpass(msg_r, 2*fc, fs); %corrected demodulated signal

            %===========DSB SC DEMODULATION FREQUENCY DOMAIN============
            lr=length (msg_r);
            fr=linspace (-fs/2,fs/2,lr); %frequency bins
            MSG_R=fftshift (fft (msg_r,lr)/lr); %frequency spectrum of demodulated signal
            
            %===========LOW PASS FILTER==================
            [b,a] = butter(6,2*fc/(fs/2)); % Butterworth filter of order 6 and cutoff frequency 2000 Hz
            msg_r = filter(b,a,msg_r); % Filtered message signal
            msg_r = msg_r - mean(msg_r); 

            %===========PLOTTING THE SIGNALS==================
            plot(app.message, t,msg);
%             title('Message Signal');
            
            plot(app.carrier, t,carr);
%             title('Carrier Signal');
            
            plot(app.modulated, t,dsb_sc);
%             title('DSB-SC Modulated Signal');
            
            plot(app.message_2, f,abs(fftshift(fft(msg))));
%             title('Frequency Spectrum of Message Signal');

            plot(app.carrier_2, f,abs(fftshift(fft(carr))));
%             title('Frequency Spectrum of Carrier Signal');

            plot(app.modulated_2, f,abs(DSB_SC));
%             title('Frequency Spectrum of DSB-SC Modulated Signal');
              
            plot(app.timeDomain, t, msg,'LineWidth',2);
            hold(app.timeDomain, 'on');
            plot(app.timeDomain, t, msg_r, 'r');
            hold(app.timeDomain, 'off');
%             title('Demodulated Signal');
            
            plot(app.freqDomain, fr,abs(MSG_R),'LineWidth',2);
            hold(app.freqDomain, 'on');
            plot(app.freqDomain, f, abs(fftshift(fft(msg))), 'r')
            hold(app.freqDomain, 'off');
%             title('Frequency Spectrum of Demodulated Signal');
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modesPage)
            app.modesPageObj = modesPage;
            app.Freq = app.modesPageObj.frontPageObj.freq;
            app.Amp = app.modesPageObj.frontPageObj.amp;
            app.Phse = app.modesPageObj.frontPageObj.phy;
            mainFun(app, app.modesPageObj.frontPageObj.amp, app.modesPageObj.frontPageObj.freq, app.modesPageObj.frontPageObj.phy);
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/amplitude-modulation?authuser=0');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Time')
            ylabel(app.message_2, 'Volts(V)')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Time')
            ylabel(app.carrier_2, 'Volts(V)')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Time')
            ylabel(app.modulated_2, 'Volts(V)')
            zlabel(app.modulated_2, 'Z')
            app.DemodulatedSignalTab = uitab(app.TabGroup);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.freqDomain = uiaxes(app.DemodulatedSignalTab);
            title(app.freqDomain, 'Frequency Domain')
            xlabel(app.freqDomain, 'Frequency (Hz)')
            ylabel(app.freqDomain, '|AM(f)|')
            zlabel(app.freqDomain, 'Z')
            app.timeDomain = uiaxes(app.DemodulatedSignalTab);
            title(app.timeDomain, 'Time Domain')
            xlabel(app.timeDomain, 'Time (sec)')
            ylabel(app.timeDomain, 'Voltage (V)')
            zlabel(app.timeDomain, 'Z')
            colormap(app.timeDomain, 'turbo')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphPage_dsbsc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end