classdef graphPage_ssb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        TabGroup                      matlab.ui.container.TabGroup
        TimeDomainTab                 matlab.ui.container.Tab
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        FrequencyKnob                 matlab.ui.control.Knob
        AmplitudeKnob                 matlab.ui.control.Knob
        modulated                     matlab.ui.control.UIAxes
        carrier                       matlab.ui.control.UIAxes
        message                       matlab.ui.control.UIAxes
        FrequencyDomainTab            matlab.ui.container.Tab
        modulated_2                   matlab.ui.control.UIAxes
        carrier_2                     matlab.ui.control.UIAxes
        message_2                     matlab.ui.control.UIAxes
        DemodulatedSignalTab          matlab.ui.container.Tab
        Time_domain                   matlab.ui.control.UIAxes
        Freq_domain                   matlab.ui.control.UIAxes
        AnalysisTab                   matlab.ui.container.Tab
        DeviationratioEditField       matlab.ui.control.NumericEditField
        DeviationratioEditFieldLabel  matlab.ui.control.Label
        BitrateEditField              matlab.ui.control.NumericEditField
        BitrateEditFieldLabel         matlab.ui.control.Label
        SNREditField                  matlab.ui.control.NumericEditField
        SNREditFieldLabel             matlab.ui.control.Label
        NoisefigureEditField          matlab.ui.control.NumericEditField
        NoisefigureEditFieldLabel     matlab.ui.control.Label
        EfficiencyEditField           matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel      matlab.ui.control.Label
        PowerEditField                matlab.ui.control.NumericEditField
        PowerEditFieldLabel           matlab.ui.control.Label
        RedundancyEditField           matlab.ui.control.NumericEditField
        RedundancyEditFieldLabel      matlab.ui.control.Label
        BandwidthEditField            matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel       matlab.ui.control.Label
        Error_signal                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modesPageObj 
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
        
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)

            % Defining message signal
            fs = 10000;
            fm = F; % Frequency of message signal
            Am = A; % Amplitude of message signal
            t = 0:0.001:1; % Time base
            
            % Message signal
            m = Am*cosd(2*pi*fm*t);
            
            % Defining carrier signal
            fc = 10*fm; % Frequency of carrier signal
            Ac = Am; % Amplitude of carrier signal
            
            % Carrier signal
            c = Ac*cosd(2*pi*fc*t);
            
            % Modulated signal
            sb = m.*Ac.*cos(2*pi*fc*t) - m.*Ac.*sin(2*pi*fc*t);
            
            % Demodulated signal
            demod_sb = hilbert(sb);
            demod_sb = abs(demod_sb);
            % Filtering and scaling demodulated signal
%             [ n, w ] = buttord(2/1000,4/1000, .5, 5);
%             [ b, a ] = butter(n,w,'low');
            [b,a] = butter(5,2*fm/fc,"low");
            demod_sb_filtered = 10*demod_sb;
            
            scaling_factor = Am/max(demod_sb_filtered);
            demod_sb_scaled = scaling_factor*demod_sb_filtered;
            
            % Plotting signals in time domain
            plot(app.message, t,m);
%             title('Message Signal');

            plot(app.carrier, t,c);
%             title('Carrier Signal');
            
            plot(app.modulated, t,sb);
%             title('Modulated Signal');
            
% %            ----- Edited -----
            m1 = abs(m);
            ylim(app.Time_domain, [0,Am]);
            plot(app.Time_domain, t,demod_sb_scaled);
            hold(app.Time_domain, 'on');
            plot(app.Time_domain, t,m1, 'LineWidth',2);
            hold(app.Time_domain, 'off');
%             title('Demodulated Signal');
            
            % Plotting signals in frequency domain
            N = length(t);
            f = (-N/2:N/2-1)*(1/(t(2)-t(1)))/N;
            sbu = fftshift(fft(sb))/N;
            sbl = fftshift(fft(m.*2.*Ac.*cos(2*pi*fc*t) + m.*2.*Ac.*sin(2*pi*fc*t)))/N;
            
            plot(app.message_2, f,abs(fftshift(fft(m))));
%             title('Frequency Spectrum of Message Signal');

            plot(app.carrier_2, f,abs(fftshift(fft(c))));
%             title('Frequency Spectrum of Carrier Signal');
            
            plot(app.modulated_2, f,abs(sbu));
%             title('Modulated Signal Spectrum');
            
            plot(app.Freq_domain, f,abs(sbl));
            hold(app.Freq_domain, 'on');
            plot(app.Freq_domain, f,abs(fftshift(fft(c))), 'LineWidth',2);
            hold(app.Freq_domain, 'off');
%             title('Demodulated Signal Spectrum');

bw = fm;
msg_r = demod_sb_scaled;
Pssb = (Ac^2 * Am^2)/4;
Pmsg = (Am^2)/2;
efficiency = (Pmsg/Pssb)*100;
redundancy = (1 - (Ac/Am))*100;
error = m - msg_r;
SNR = 10*log10(Pmsg / var(error));
noise_figure = (Pssb / Pmsg) - 1;

deviation_ratio = Ac/Am;
bitrate = log2(1 + (Ac^2/(var(error))));
app.BandwidthEditField.Value = bw;
app.BitrateEditField.Value = bitrate;
app.DeviationratioEditField.Value = deviation_ratio;
app.SNREditField.Value = SNR;
app.NoisefigureEditField.Value = noise_figure;
app.EfficiencyEditField.Value = efficiency;
app.RedundancyEditField.Value = redundancy;

plot(app.Error_signal,t,error);


       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modesPage)
            app.modesPageObj = modesPage;
            app.Freq = app.modesPageObj.frontPageObj.freq;
            app.Amp = app.modesPageObj.frontPageObj.amp;
            app.Phse = app.modesPageObj.frontPageObj.phy;
            mainFun(app, app.modesPageObj.frontPageObj.amp, app.modesPageObj.frontPageObj.freq, app.modesPageObj.frontPageObj.phy);
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            web('https://sites.google.com/iiits.in/fundamentals-of-communication/amplitude-modulation?authuser=0');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'help.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Time')
            ylabel(app.message_2, '|m(f)|')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Frequency in Hz')
            ylabel(app.carrier_2, '|c(f)|')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Frequency in Hz')
            ylabel(app.modulated_2, '|AM(f)|')
            zlabel(app.modulated_2, 'Z')
            app.DemodulatedSignalTab = uitab(app.TabGroup);
            app.DemodulatedSignalTab.Title = 'Demodulated Signal';
            app.Freq_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Freq_domain, 'Demodulated Signal and Message Signal in Frequency domain')
            xlabel(app.Freq_domain, 'Frequency (Hz)')
            ylabel(app.Freq_domain, '|AM(F)|')
            zlabel(app.Freq_domain, 'Z')
            app.Time_domain = uiaxes(app.DemodulatedSignalTab);
            title(app.Time_domain, 'Demodulated Signal and Message Signal in time domain')
            xlabel(app.Time_domain, 'Time (s)')
            ylabel(app.Time_domain, 'Amplitude (Volts)')
            zlabel(app.Time_domain, 'Z')
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.Error_signal = uiaxes(app.AnalysisTab);
            title(app.Error_signal, 'Error Signal')
            xlabel(app.Error_signal, 'Time')
            ylabel(app.Error_signal, 'Amplitude')
            zlabel(app.Error_signal, 'Z')
            app.BandwidthEditFieldLabel = uilabel(app.AnalysisTab);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.RedundancyEditFieldLabel = uilabel(app.AnalysisTab);
            app.RedundancyEditFieldLabel.Text = 'Redundancy';
            app.RedundancyEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.AnalysisTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.AnalysisTab);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.NoisefigureEditFieldLabel = uilabel(app.AnalysisTab);
            app.NoisefigureEditFieldLabel.Text = 'Noise figure ';
            app.NoisefigureEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.SNREditFieldLabel = uilabel(app.AnalysisTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.AnalysisTab, 'numeric');
            app.BitrateEditFieldLabel = uilabel(app.AnalysisTab);
            app.BitrateEditFieldLabel.Text = 'Bit rate';
            app.BitrateEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DeviationratioEditFieldLabel = uilabel(app.AnalysisTab);
            app.DeviationratioEditFieldLabel.Text = 'Deviation ratio ';
            app.DeviationratioEditField = uieditfield(app.AnalysisTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphPage_ssb(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end