classdef graphPage_vsb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        TimeDomainTab            matlab.ui.container.Tab
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        AmplitudeEditField       matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        FrequencyKnob            matlab.ui.control.Knob
        AmplitudeKnob            matlab.ui.control.Knob
        modulated                matlab.ui.control.UIAxes
        carrier                  matlab.ui.control.UIAxes
        message                  matlab.ui.control.UIAxes
        FrequencyDomainTab       matlab.ui.container.Tab
        modulated_2              matlab.ui.control.UIAxes
        carrier_2                matlab.ui.control.UIAxes
        message_2                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        modesPageObj 
    end
    
    properties (Access = private)
        Freq
        Amp
        Phse
        
    end
    
    methods (Access = public)
        
        function mainFun(app, A, F, phy)

            m = 0.5;  
            Am = A;   
            fm = F;   
            K = 0.5; % VSB filter roll-off factor
            fs = 100*fm;  
            Tm = 1/fm;    
            t = -100*Tm:1/fs:100*Tm;
            ym = Am*cosd(2*pi*fm*t + phy);
            plot(app.message, 10.*t, ym);

            t1 = -1*Tm:1/fs:1*Tm;
            Ac = Am/m; 
            fc = 100*fm;  
            yc = Ac*sin(2*pi*fc*t); 

            % Apply VSB modulation
            % Create VSB filter
            h = fir1(100,K/(fs/2),'low');
            % Apply VSB filter to message signal
            message_filtered = filter(h,1,ym);
            % Modulate message signal onto carrier signal
            AM = message_filtered.*yc;
           
            
            plot(app.carrier, t, yc);
            plot(app.modulated, t, AM,'r');
            
            L = length(t);  
            
            Nf=L ; 
            AM_fft = fftshift(fft(AM,Nf));     
            mf = fftshift(fft(ym,Nf));    
            cf = fftshift(fft(yc,Nf));     
            f = (-Nf/2:1:Nf/2-1)*fs/Nf;      
            plot(app.message_2,f,abs(mf));

            plot(app.carrier_2,f,abs(cf));   

            plot(app.modulated_2,f,abs(AM_fft)); 


%             L = length(t);  
            
%             Nf=L ; 
%             AM_fft = fftshift(fft(AM,Nf));     
%             mf = fftshift(fft(ym,Nf));    
%             cf = fftshift(fft(yc,Nf));     
%             f = (-Nf/2:1:Nf/2-1)*fs/Nf;      
%             plot(f,abs(mf)); 
%             xlabel("Frequency (Hz) -->");
%             ylabel(" |m(f)| ");
%             title ('Frequency domain Message Signal');
%             
%             plot(f,abs(cf));   
%             ylabel ('|c(f)|)');
%             xlabel("Frequency (Hz) -->");
%             title ('Frequency Domain Carrier Signal');
%             
%             plot(f,abs(AM_fft)); 
%             title('Freq Response of AM Signal');
%             xlabel('frequency (Hz)');
%             ylabel(' |AM(F)|')
            
%             Vc = 2*AM.*sin(2*pi*fc*t);
%             
%             [b,a] = butter(4,fc*2/fs);
%             ym_rec = filter(b,a,Vc);
%             ym_rec=ym_rec- mean(ym_rec); 
% 
%             plot(t, ym_rec,'LineWidth',2);
%             hold on;
%             plot(t,ym,'r');   
%             title('demodulated AM signal with origional message signal');
%             legend("Demodulated AM signal","message signal");
%             xlabel('Time (s)');
%             ylabel('Amplitude (Volts)');
            
%             ym_rec_fft = fftshift(fft(ym_rec,Nf));             
%             f = (-Nf/2:1:Nf/2-1)*fs/Nf;
%             plot(f,abs(ym_rec_fft));
%             hold on;
%             title('Freq Response of demodulated AM signal y_m(t)');
%             xlabel('f(Hz)');
%             ylabel('|AM(F)|');
%             
%             Nf=L;            
%             f_ym = fftshift(fft(ym,Nf));
%             f = (-Nf/2:1:Nf/2-1)*fs/Nf;
%             plot(f,abs(f_ym));
%             xlabel("frequency (Hz)");
%             ylabel("|m(f)|");
%             title("Frequency response of message signal m(t)");

       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, modesPage)
            app.modesPageObj = modesPage;
            app.Freq = app.modesPageObj.frontPageObj.freq;
            app.Amp = app.modesPageObj.frontPageObj.amp;
            app.Phse = app.modesPageObj.frontPageObj.phy;
            mainFun(app, app.modesPageObj.frontPageObj.amp, app.modesPageObj.frontPageObj.freq, app.modesPageObj.frontPageObj.phy);
        end

        % Value changing function: AmplitudeKnob
        function AmplitudeKnobValueChanging(app, event)
            if app.FrequencyEditField.Value==0
                app.FrequencyEditField.Value=app.Freq;
            end
            value = event.Value;
            app.AmplitudeEditField.Value = value + app.Amp;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end

        % Value changing function: FrequencyKnob
        function FrequencyKnobValueChanging(app, event)
            if app.AmplitudeEditField.Value==0
                app.AmplitudeEditField.Value=app.Amp;
            end
            value = event.Value;
            app.FrequencyEditField.Value = value + app.Freq;
            mainFun(app,app.AmplitudeEditField.Value,app.FrequencyEditField.Value,app.Phse);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.message = uiaxes(app.TimeDomainTab);
            title(app.message, 'Message Signal')
            xlabel(app.message, 'Time')
            ylabel(app.message, 'Volts(V)')
            zlabel(app.message, 'Z')
            app.carrier = uiaxes(app.TimeDomainTab);
            title(app.carrier, 'Carrier Signal')
            xlabel(app.carrier, 'Time')
            ylabel(app.carrier, 'Volts(V)')
            zlabel(app.carrier, 'Z')
            app.modulated = uiaxes(app.TimeDomainTab);
            title(app.modulated, 'Modulated Signal')
            xlabel(app.modulated, 'Time')
            ylabel(app.modulated, 'Volts(V)')
            zlabel(app.modulated, 'Z')
            app.AmplitudeKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.AmplitudeKnob.Limits = [-100 100];
            app.AmplitudeKnob.ValueChangingFcn = createCallbackFcn(app, @AmplitudeKnobValueChanging, true);
            app.FrequencyKnob = uiknob(app.TimeDomainTab, 'continuous');
            app.FrequencyKnob.Limits = [-100 100];
            app.FrequencyKnob.ValueChangingFcn = createCallbackFcn(app, @FrequencyKnobValueChanging, true);
            app.AmplitudeEditFieldLabel = uilabel(app.TimeDomainTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.AmplitudeEditField.Editable = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.TimeDomainTab);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.TimeDomainTab, 'numeric');
            app.FrequencyEditField.Editable = 'off';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain';
            app.message_2 = uiaxes(app.FrequencyDomainTab);
            title(app.message_2, 'Message Signal')
            xlabel(app.message_2, 'Frequency in Hz')
            ylabel(app.message_2, '|m(f)|')
            zlabel(app.message_2, 'Z')
            app.carrier_2 = uiaxes(app.FrequencyDomainTab);
            title(app.carrier_2, 'Carrier Signal')
            xlabel(app.carrier_2, 'Frequecny in Hz')
            ylabel(app.carrier_2, '|c(f)|')
            zlabel(app.carrier_2, 'Z')
            app.modulated_2 = uiaxes(app.FrequencyDomainTab);
            title(app.modulated_2, 'Modulated Signal')
            xlabel(app.modulated_2, 'Frequecny in Hz')
            ylabel(app.modulated_2, '|AM(f)|')
            zlabel(app.modulated_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graphPage_vsb(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end