classdef modesPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnalogModulationPanel          matlab.ui.container.Panel
        AmplitudeModulationDropDown_2  matlab.ui.control.DropDown
        AmplitudeModulationDropDown_2Label  matlab.ui.control.Label
        PhaseModulationDropDown        matlab.ui.control.DropDown
        PhaseModulationLabel           matlab.ui.control.Label
        FrequencyModulationDropDown    matlab.ui.control.DropDown
        FrequencyModulationDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        ampValue
        freqValue
        phaseValue
    end
    
    properties (Access = public)
        frontPageObj
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, frontPage)
            app.frontPageObj = frontPage;
        end

        % Value changed function: AmplitudeModulationDropDown_2
        function AmplitudeModulationDropDown_2ValueChanged(app, event)
            value = app.AmplitudeModulationDropDown_2.Value;
            if strcmp(value, "DSB-FC")
                graphPage_dsbfc(app);
                delete(app);
            end
            if strcmp(value, "DSB-SC")
                graphPage_dsbsc(app);
                delete(app);
            end
            if strcmp(value, "SSB-SC")
                graphPage_ssb(app);
                delete(app);
            end
            if strcmp(value, "VSB")
                graphPage_vsb(app);
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalogModulationPanel = uipanel(app.UIFigure);
            app.AnalogModulationPanel.Title = 'Analog Modulation';
            app.FrequencyModulationDropDownLabel = uilabel(app.AnalogModulationPanel);
            app.FrequencyModulationDropDownLabel.Text = 'Frequency Modulation';
            app.FrequencyModulationDropDown = uidropdown(app.AnalogModulationPanel);
            app.FrequencyModulationDropDown.Items = {'NBFM', 'WBFM', ''};
            app.FrequencyModulationDropDown.Placeholder = 'Choose any technique';
            app.FrequencyModulationDropDown.Value = '';
            app.PhaseModulationLabel = uilabel(app.AnalogModulationPanel);
            app.PhaseModulationLabel.Text = 'Phase Modulation  ';
            app.PhaseModulationDropDown = uidropdown(app.AnalogModulationPanel);
            app.PhaseModulationDropDown.Items = {'PM', ''};
            app.PhaseModulationDropDown.Placeholder = 'Choose any technique';
            app.PhaseModulationDropDown.Value = '';
            app.AmplitudeModulationDropDown_2Label = uilabel(app.AnalogModulationPanel);
            app.AmplitudeModulationDropDown_2Label.Text = 'Amplitude Modulation';
            app.AmplitudeModulationDropDown_2 = uidropdown(app.AnalogModulationPanel);
            app.AmplitudeModulationDropDown_2.Items = {'DSB-FC', 'DSB-SC', 'SSB-SC', 'VSB', ''};
            app.AmplitudeModulationDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeModulationDropDown_2ValueChanged, true);
            app.AmplitudeModulationDropDown_2.Placeholder = 'Choose any technique';
            app.AmplitudeModulationDropDown_2.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modesPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end